classdef PETMAT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label                           matlab.ui.control.Label
        CompletedGauge                  matlab.ui.control.LinearGauge
        CompletedLabel                  matlab.ui.control.Label
        SmoothingthePETimageListBox     matlab.ui.control.ListBox
        SmoothingthePETimageLabel       matlab.ui.control.Label
        NormalizationinterpolationListBox  matlab.ui.control.ListBox
        RegistrationinterpolationLabel  matlab.ui.control.Label
        CoregistrationinterpolationListBox  matlab.ui.control.ListBox
        CoregistrationinterplationLabel  matlab.ui.control.Label
        PETMATLabel                     matlab.ui.control.Label
        SubjectIDsTextArea              matlab.ui.control.TextArea
        SubjectIDsLabel                 matlab.ui.control.Label
        ExitButton                      matlab.ui.control.Button
        AnalyzeButton                   matlab.ui.control.Button
        DatafolderpathEditField         matlab.ui.control.EditField
        DatafolderpathEditFieldLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            
                        
            % Get the inputs from the GUI
            subject_list = app.SubjectIDsTextArea.Value;
            address_base = app.DatafolderpathEditField.Value;
            
            try
                % Check if the subject list is empty
                if isempty(subject_list)
                    error('SubjectListError', 'The subject list cannot be empty. Please enter the subject IDs.');
                end
                
                % Check if the address base (directory) is empty
                if isempty(address_base)
                    error('AddressBaseError', 'The data folder path cannot be empty. Please enter a valid directory path.');
                end
                
                % Check if the address base is a valid directory
                if ~isfolder(address_base)
                    error('InvalidDirectoryError', 'The specified directory does not exist. Please provide a valid directory path.');
                end
                
                % Additional validation (if needed)
                % For example: Check if the file format is valid
                % Assuming the user needs to enter file paths, not just directories
                % if ~all(cellfun(@(x) endsWith(x, '.nii'), subject_list))
                %     error('FileFormatError', 'All files must be in .nii format.');
                % end
                
                % If all checks pass, process the data
                disp('Inputs are valid. Proceeding with analysis...');
                
            catch ME
                % Display a meaningful error message in the GUI
                uialert(app.UIFigure, ME.message, 'Input Error', 'Icon', 'error');
            end

            
            subject_IDs = split(subject_list, ',');
            subject_IDs = strtrim(subject_IDs);

            coreg_interp = (app.CoregistrationinterpolationListBox.ValueIndex) - 1;
            nomal_interp = (app.NormalizationinterpolationListBox.ValueIndex) - 1;

            smooth_mode = app.SmoothingthePETimageListBox.ValueIndex;
            if (smooth_mode == 1)
                smooth_kernel = [5 5 5];
            elseif (smooth_mode == 2)
                smooth_kernel = [8 8 8];
            elseif (smooth_mode == 3)
                smooth_kernel = [10 10 10];
            end

            % Show the percentage of pre-processing completion
            app.CompletedGauge.Value = 0;
            drawnow; % Force GUI to update immediately
            addition = 1/(length(subject_IDs) * 3); % will be added to the %completion during process


            for k = 1:length(subject_IDs) %itterate on subjects

                current_subject = subject_IDs{k};
                
                current_address = string(fullfile(address_base, current_subject));
                disp(current_address)
                disp(current_address)
                cd(current_address);

                % Delete PET.nii
                PET_path = fullfile(current_address, 'PET.nii');
                if isfile(PET_path)
                    delete(PET_path);
                end

                coefficient = 1;

                nii_files = dir('*.nii'); %number of nifti file
                flag = length(nii_files) - 1; % number of PET scans

                disp(' ')
                disp('----------------------------------------------------------------')
                disp(['************* Subject: ' current_subject])
                disp(['Number of PET volumes: ' num2str(flag)])


                disp('---Setting image origins')
                for i = 1:numel(nii_files)

                    img_path = fullfile(nii_files(i).folder, nii_files(i).name);
                    V = spm_vol(img_path);
                    center_mm = (V.dim + 1)/2 * V.mat(1:3, 1:3)';
                    V.mat(1:3, 4) = -center_mm;
                    vol_data = spm_read_vols(V);

                    [x,name,y] = fileparts(nii_files(i).name);
                    if (name == "T1")
                        new_img_path = fullfile(nii_files(i).folder, [name '.nii']);
                    else
                        new_img_path = fullfile(nii_files(i).folder, [num2str(i)-1 name '.nii']);
                    end
                    V.fname = new_img_path;
                    spm_write_vol(V, vol_data);

                end
                %--------------------------------------------

                disp('')
                disp('---Coregistration')
                cd(current_address)
                ref_img = "T1.nii"; ref_vol = spm_vol(char(ref_img));
                src_img = dir("1*.nii").name; src_vol = spm_vol(char(src_img));
                matlabbatch{1}.spm.spatial.coreg.estimate.ref = {ref_vol.fname};
                matlabbatch{1}.spm.spatial.coreg.estimate.source = {src_vol.fname};
                matlabbatch{1}.spm.spatial.coreg.estimate.other = {''};
                spm_jobman('run', matlabbatch)
                matlabbatch{2}.spm.spatial.coreg.write.ref = {ref_vol.fname};
                matlabbatch{2}.spm.spatial.coreg.write.source = {src_vol.fname};
                matlabbatch{2}.spm.spatial.coreg.write.roptions.interp = coreg_interp;
                spm_jobman('run', matlabbatch)

                clear("matlabbatch");

                ref_img = dir("r1*.nii").name; ref_vol = spm_vol(char(ref_img));

                if flag == 6
                    for i = 2:6 % CHANGE 

                        src_img = dir([num2str(i) '*.nii']).name; src_vol = spm_vol(char(src_img));
                        matlabbatch{1}.spm.spatial.coreg.estimate.ref = {ref_vol.fname};
                        matlabbatch{1}.spm.spatial.coreg.estimate.source = {src_vol.fname};
                        matlabbatch{1}.spm.spatial.coreg.estimate.other = {''};
                        spm_jobman('run', matlabbatch)
                        matlabbatch{2}.spm.spatial.coreg.write.ref = {ref_vol.fname};
                        matlabbatch{2}.spm.spatial.coreg.write.source = {src_vol.fname};
                        matlabbatch{2}.spm.spatial.coreg.write.roptions.interp = 1;
                        spm_jobman('run', matlabbatch)

                    end

                    spm_imcalc({dir('r1*.nii').name, dir('r2*.nii').name, dir('r3*.nii').name,dir('r4*.nii').name,dir('r5*.nii').name,dir('r6*.nii').name}, 'coregister_PET.nii', 'i1+i2+i3+i4+i5+i6'); %CHANGE

                elseif flag == 2

                    i = 2;

                    src_img = dir([num2str(i) '*.nii']).name; src_vol = spm_vol(char(src_img));
                    matlabbatch{1}.spm.spatial.coreg.estimate.ref = {ref_vol.fname};
                    matlabbatch{1}.spm.spatial.coreg.estimate.source = {src_vol.fname};
                    matlabbatch{1}.spm.spatial.coreg.estimate.other = {''};
                    spm_jobman('run', matlabbatch)
                    matlabbatch{2}.spm.spatial.coreg.write.ref = {ref_vol.fname};
                    matlabbatch{2}.spm.spatial.coreg.write.source = {src_vol.fname};
                    matlabbatch{2}.spm.spatial.coreg.write.roptions.interp = 1;
                    spm_jobman('run', matlabbatch)

                    spm_imcalc({dir('r1*.nii').name, dir('r2*.nii').name}, 'coregister_PET.nii', 'i1+i2');


                elseif flag == 1

                    spm_imcalc({dir('r1*.nii').name}, 'coregister_PET.nii', 'i1 * 1'); %Just to change the name

                end


                clear("matlabbatch");

                % Show the percentage of pre-processing completion
                app.CompletedGauge.Value = (k/length(subject_IDs) - 2*addition) * 100;
                drawnow; % Force GUI to update immediately


                %--------------------------------------------

                disp('')
                disp('---Normalization')

                spm('defaults', 'FMRI');
                spm_jobman('initcfg');
                matlabbatch{1}.spm.spatial.normalise.est.subj.vol = {'T1.nii'};
                spm_jobman('run', matlabbatch)

                T1_vol = spm_vol('T1.nii');
                PET_vol = spm_vol('coregister_PET.nii');
                def_vol = spm_vol('y_T1.nii');

                clear("matlabbatch");

                matlabbatch{1}.spm.spatial.normalise.write.subj.def = {'y_T1.nii'};
                matlabbatch{1}.spm.spatial.normalise.write.subj.resample = {'T1.nii'};
                matlabbatch{1}.spm.spatial.normalise.write.roptions.bb = [-78 -112 -70; 78 76 85];
                matlabbatch{1}.spm.spatial.normalise.write.roptions.vox = [1 1 1];
                matlabbatch{1}.spm.spatial.normalise.write.roptions.interp = nomal_interp;

                spm_jobman('run', matlabbatch)

                normalized_path = fullfile(fileparts('T1.nii'), ['w' T1_vol.fname]);
                movefile(normalized_path, 'std_T1.nii', 'f')


                clear("matlabbatch");

                matlabbatch{1}.spm.spatial.normalise.write.subj.def = {'y_T1.nii'};
                matlabbatch{1}.spm.spatial.normalise.write.subj.resample = {'coregister_PET.nii'};
                matlabbatch{1}.spm.spatial.normalise.write.roptions.bb = [-78 -112 -70; 78 76 85];
                matlabbatch{1}.spm.spatial.normalise.write.roptions.vox = [1 1 1];
                matlabbatch{1}.spm.spatial.normalise.write.roptions.interp = nomal_interp;

                spm_jobman('run', matlabbatch)

                normalized_path = fullfile(fileparts('coregister_PET.nii'), ['w' PET_vol.fname]);
                movefile(normalized_path, 'std_coregister_PET.nii', 'f')


                % Show the percentage of pre-processing completion
                app.CompletedGauge.Value = (k/length(subject_IDs) - 1*addition) * 100;
                drawnow; % Force GUI to update immediately

                % ------------------------------------
                disp(' ')
                disp('---Segmenting std_T1')

                std_T1_vol = spm_vol('std_T1.nii');

                clear("matlabbatch");
                matlabbatch{1}.spm.spatial.preproc.channel(1).vols = {std_T1_vol.fname};
                matlabbatch{1}.spm.spatial.preproc.channel(1).biasreg = 0.001;
                matlabbatch{1}.spm.spatial.preproc.channel(1).biasfwhm = 60;
                matlabbatch{1}.spm.spatial.preproc.channel(1).write = [0 1];
                spm_jobman('run', matlabbatch)
                clear("matlabbatch");

                spm_imcalc({'c1std_T1.nii'}, 'GM_MASK.nii', 'i1 > 0.2');

                spm_imcalc({'GM_MASK.nii', 'std_T1.nii'}, 'GM.nii', 'i1 .* i2');


                %--------------------------------------------
                disp(' ')

                if (smooth_mode ~= 0)

                    disp('---Smoothing std_PET')
    
                    std_PET_vol = spm_vol('std_coregister_PET.nii');
    
                    clear("matlabbatch");
                    matlabbatch{1}.spm.spatial.smooth.data = {std_PET_vol.fname};
                    matlabbatch{1}.spm.spatial.smooth.fwhm = smooth_kernel;
                    output_path = [pwd, spm_file(std_PET_vol.fname, 'basename')];
                    matlabbatch{1}.spm.spatial.smooth.prefix = 'smooth_';
                    matlabbatch{1}.spm.spatial.smooth.data = {std_PET_vol.fname};
                    spm_jobman('run', matlabbatch)
                    clear("matlabbatch");

                    disp(' ')
                    disp('---Finalizing')
    
                    expression = sprintf('i1 * %g', coefficient);
                    disp(pwd)
                    disp(expression)
                    spm_imcalc({'smooth_std_coregister_PET.nii'}, 'SUV.nii', expression);
    
                    spm_imcalc({'SUV.nii', 'GM_MASK.nii'}, 'SUV_GM.nii', 'i1 .* i2');

                else
                    disp(' ')
                    disp('---Finalizing')
    
                    expression = sprintf('i1 * %g', coefficient);
                    disp(pwd)
                    disp(expression)
                    spm_imcalc({'std_coregister_PET.nii'}, 'SUV.nii', expression);
    
                    spm_imcalc({'SUV.nii', 'GM_MASK.nii'}, 'SUV_GM.nii', 'i1 .* i2');
                end


            % Show the percentage of pre-processing completion
            app.CompletedGauge.Value = (k/length(subject_IDs)) * 100;
            drawnow; % Force GUI to update immediately


            end

            disp('------')
            disp('Pre-processings ended without any errors.')
    

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq();
        end

        % Callback function
        function TestButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DatafolderpathEditFieldLabel = uilabel(app.UIFigure);
            app.DatafolderpathEditFieldLabel.Text = 'Data folder path:';
            app.DatafolderpathEditField = uieditfield(app.UIFigure, 'text');
            app.DatafolderpathEditField.Tag = 'data_folder_address';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.SubjectIDsLabel = uilabel(app.UIFigure);
            app.SubjectIDsLabel.Text = 'Subject IDs:';
            app.SubjectIDsTextArea = uitextarea(app.UIFigure);
            app.PETMATLabel = uilabel(app.UIFigure);
            app.PETMATLabel.Text = 'PETMAT';
            app.CoregistrationinterplationLabel = uilabel(app.UIFigure);
            app.CoregistrationinterplationLabel.Text = 'Co-registration interpolation:';
            app.CoregistrationinterpolationListBox = uilistbox(app.UIFigure);
            app.CoregistrationinterpolationListBox.Items = {'Nearest neighbor', 'Trilinear', 'Quadratic B-spline', 'Qubic B-spline', '4th-degree B-spline', '5th-degree B-spline', '6th-degree B-spline', '7th-degree B-spline'};
            app.CoregistrationinterpolationListBox.Value = 'Trilinear';
            app.RegistrationinterpolationLabel = uilabel(app.UIFigure);
            app.RegistrationinterpolationLabel.Text = 'Normalization interpolation:';
            app.NormalizationinterpolationListBox = uilistbox(app.UIFigure);
            app.NormalizationinterpolationListBox.Items = {'Nearest neighbor', 'Trilinear', 'Quadratic B-spline', 'Qubic B-spline', '4th-degree B-spline', '5th-degree B-spline', '6th-degree B-spline', '7th-degree B-spline'};
            app.NormalizationinterpolationListBox.Value = '4th-degree B-spline';
            app.SmoothingthePETimageLabel = uilabel(app.UIFigure);
            app.SmoothingthePETimageLabel.Text = 'Smoothing the PET image:';
            app.SmoothingthePETimageListBox = uilistbox(app.UIFigure);
            app.SmoothingthePETimageListBox.Items = {'No smoothing', '5 FWHM', '8 FWHM', '10 FWHM'};
            app.SmoothingthePETimageListBox.Value = '5 FWHM';
            app.CompletedLabel = uilabel(app.UIFigure);
            app.CompletedLabel.Text = 'Completed';
            app.CompletedGauge = uigauge(app.UIFigure, 'linear');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PETMAT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end